classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        TabMoveControl            matlab.ui.container.Tab
        GridLayout                matlab.ui.container.GridLayout
        dcudltlaPanel             matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout4               matlab.ui.container.GridLayout
        GridLayout7               matlab.ui.container.GridLayout
        EditFieldRadius           matlab.ui.control.NumericEditField
        RadiusmmEditFieldLabel    matlab.ui.control.Label
        GridLayout6               matlab.ui.container.GridLayout
        EditFieldMoveTo           matlab.ui.control.EditField
        XYEditFieldLabel          matlab.ui.control.Label
        GridLayout5               matlab.ui.container.GridLayout
        EditFieldArduinoSelect    matlab.ui.control.NumericEditField
        COMEditFieldLabel         matlab.ui.control.Label
        ButtonCircle              matlab.ui.control.Button
        ButtonRectangle           matlab.ui.control.Button
        ButtonMoveTo              matlab.ui.control.Button
        ButtonHome                matlab.ui.control.Button
        ButtonArduinoSelect       matlab.ui.control.Button
        ButtonCreateObject        matlab.ui.control.Button
        GridLayout3               matlab.ui.container.GridLayout
        LogAreaLog                matlab.ui.control.TextArea
        LogTextAreaLabel          matlab.ui.control.Label
        FigureMainPlot            matlab.ui.control.UIAxes
        TabStepperControl         matlab.ui.container.Tab
        Label2                    matlab.ui.control.Label
        LabelRightStepper         matlab.ui.control.Label
        LabelLeftStepper          matlab.ui.control.Label
        LabelKroky                matlab.ui.control.Label
        KnobLeftStepper           matlab.ui.control.Knob
        zenlevhomotoruKnobLabel   matlab.ui.control.Label
        KnobRightStepper          matlab.ui.control.Knob
        zenpravhomotoruKnobLabel  matlab.ui.control.Label
        GaugeLeftStepper          matlab.ui.control.SemicircularGauge
        LevmotorGaugeLabel        matlab.ui.control.Label
        GaugeRightStepper         matlab.ui.control.SemicircularGauge
        PravmotorGaugeLabel       matlab.ui.control.Label
        TabExtraParams            matlab.ui.container.Tab
        EditFieldMicrostepping    matlab.ui.control.NumericEditField
        MikrokrokyEditFieldLabel  matlab.ui.control.Label
        PoetdlenvelikostipemstnEditField  matlab.ui.control.NumericEditField
        PoetdlenvelikostipemstnEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        %Objekty
        robot_object % hlavní objekt robota 
        logging_object %logovací objekt
        %Stavové proměnné
        ready_to_use
        logging_device_connected
    end
    
    methods (Access = private)
        
        function logText(obj, text)
            %Vypsání do normální konzole
            disp(text)

            %Případné logování do konzole
            if obj.logging_device_connected
                old_text_string = obj.logging_object.Value;
                new_string = [old_text_string; text];
                obj.logging_object.Value = new_string;
                scroll(obj.logging_object, 'bottom')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            app.ready_to_use = 0;
            app.logging_device_connected = 0;
            app.logging_object = app.LogAreaLog;
        end

        % Button pushed function: ButtonCreateObject
        function ButtonCreateObjectPushed(app, event)
            app.robot_object = robot(app.EditFieldMicrostepping.Value,...
                app.logging_object);
            app.logging_device_connected = 1;
            app.robot_object.avaiablePorts();
        end

        % Button pushed function: ButtonHome
        function ButtonHomePushed(app, event)
            app.robot_object.moveTo([25; 175]);
        end

        % Button pushed function: ButtonArduinoSelect
        function ButtonArduinoSelectPushed(app, event)
             if app.robot_object.connectArduino(app.EditFieldArduinoSelect.Value)
                 app.ready_to_use = 1;
                 app.ButtonArduinoSelect.BackgroundColor = [0 1 0];
                 app.ButtonArduinoSelect.Text = "Připojeno";
             end
        end

        % Button pushed function: ButtonRectangle
        function ButtonRectanglePushed(app, event)
            app.logText("Jedu čtverec")
            app.robot_object.rectangle;
            app.logText("Dojel čtverec")
        end

        % Button pushed function: ButtonCircle
        function ButtonCirclePushed(app, event)
            radius = app.EditFieldRadius.Value;
            app.logText("Jedu kruh o poloměru r = "+radius+" mm")
            app.robot_object.circle(radius); 
            app.logText("Dojel kruh")
        end

        % Button pushed function: ButtonMoveTo
        function ButtonMoveToPushed(app, event)
            coordiantes = str2num(app.EditFieldMoveTo.Value);
            app.logText("Přesun na ["+coordiantes(1)+", "+coordiantes(2)+"]")
            app.robot_object.moveTo(coordiantes);
            app.logText("Dosunuto")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabMoveControl = uitab(app.TabGroup);
            app.TabMoveControl.Title = 'Řízení pohybu';
            app.GridLayout = uigridlayout(app.TabMoveControl);
            app.FigureMainPlot = uiaxes(app.GridLayout);
            title(app.FigureMainPlot, 'Interaktivní plocha')
            zlabel(app.FigureMainPlot, 'Z')
            app.FigureMainPlot.XLim = [-50 100];
            app.FigureMainPlot.YLim = [50 200];
            app.dcudltlaPanel = uipanel(app.GridLayout);
            app.dcudltlaPanel.Title = 'Řídící čudlítla';
            app.GridLayout2 = uigridlayout(app.dcudltlaPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.LogTextAreaLabel = uilabel(app.GridLayout3);
            app.LogTextAreaLabel.Text = 'Log:';
            app.LogAreaLog = uitextarea(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.ButtonCreateObject = uibutton(app.GridLayout4, 'push');
            app.ButtonCreateObject.ButtonPushedFcn = createCallbackFcn(app, @ButtonCreateObjectPushed, true);
            app.ButtonCreateObject.Text = 'Vytvoření řízení';
            app.ButtonArduinoSelect = uibutton(app.GridLayout4, 'push');
            app.ButtonArduinoSelect.ButtonPushedFcn = createCallbackFcn(app, @ButtonArduinoSelectPushed, true);
            app.ButtonArduinoSelect.Text = 'Výběr arduina';
            app.ButtonHome = uibutton(app.GridLayout4, 'push');
            app.ButtonHome.ButtonPushedFcn = createCallbackFcn(app, @ButtonHomePushed, true);
            app.ButtonHome.Text = 'HOME';
            app.ButtonMoveTo = uibutton(app.GridLayout4, 'push');
            app.ButtonMoveTo.ButtonPushedFcn = createCallbackFcn(app, @ButtonMoveToPushed, true);
            app.ButtonMoveTo.Text = 'Přesun na:';
            app.ButtonRectangle = uibutton(app.GridLayout4, 'push');
            app.ButtonRectangle.ButtonPushedFcn = createCallbackFcn(app, @ButtonRectanglePushed, true);
            app.ButtonRectangle.Text = 'Čtverec';
            app.ButtonCircle = uibutton(app.GridLayout4, 'push');
            app.ButtonCircle.ButtonPushedFcn = createCallbackFcn(app, @ButtonCirclePushed, true);
            app.ButtonCircle.Text = 'Kruh';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.COMEditFieldLabel = uilabel(app.GridLayout5);
            app.COMEditFieldLabel.Text = 'COM:';
            app.EditFieldArduinoSelect = uieditfield(app.GridLayout5, 'numeric');
            app.EditFieldArduinoSelect.Value = 5;
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.XYEditFieldLabel = uilabel(app.GridLayout6);
            app.XYEditFieldLabel.Text = '[X, Y]';
            app.EditFieldMoveTo = uieditfield(app.GridLayout6, 'text');
            app.EditFieldMoveTo.Value = '[25 175]';
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.RadiusmmEditFieldLabel = uilabel(app.GridLayout7);
            app.RadiusmmEditFieldLabel.Text = 'Radius [mm]';
            app.EditFieldRadius = uieditfield(app.GridLayout7, 'numeric');
            app.EditFieldRadius.Value = 10;
            app.TabStepperControl = uitab(app.TabGroup);
            app.TabStepperControl.Title = 'Řízení motorů';
            app.PravmotorGaugeLabel = uilabel(app.TabStepperControl);
            app.PravmotorGaugeLabel.Text = 'Pravý motor';
            app.GaugeRightStepper = uigauge(app.TabStepperControl, 'semicircular');
            app.GaugeRightStepper.ScaleDirection = 'counterclockwise';
            app.LevmotorGaugeLabel = uilabel(app.TabStepperControl);
            app.LevmotorGaugeLabel.Text = 'Levý motor';
            app.GaugeLeftStepper = uigauge(app.TabStepperControl, 'semicircular');
            app.GaugeLeftStepper.ScaleDirection = 'counterclockwise';
            app.zenpravhomotoruKnobLabel = uilabel(app.TabStepperControl);
            app.zenpravhomotoruKnobLabel.Text = 'Řízení pravého motoru';
            app.KnobRightStepper = uiknob(app.TabStepperControl, 'continuous');
            app.zenlevhomotoruKnobLabel = uilabel(app.TabStepperControl);
            app.zenlevhomotoruKnobLabel.Text = 'Řízení levého motoru';
            app.KnobLeftStepper = uiknob(app.TabStepperControl, 'continuous');
            app.LabelKroky = uilabel(app.TabStepperControl);
            app.LabelKroky.Text = 'Kroky:';
            app.LabelLeftStepper = uilabel(app.TabStepperControl);
            app.LabelLeftStepper.Text = 'Kroky:';
            app.LabelRightStepper = uilabel(app.TabStepperControl);
            app.Label2 = uilabel(app.TabStepperControl);
            app.Label2.Text = 'Label2';
            app.TabExtraParams = uitab(app.TabGroup);
            app.TabExtraParams.Title = 'Extra nastavení';
            app.PoetdlenvelikostipemstnEditFieldLabel = uilabel(app.TabExtraParams);
            app.PoetdlenvelikostipemstnEditFieldLabel.Text = 'Počet dělení velikosti přemístění';
            app.PoetdlenvelikostipemstnEditField = uieditfield(app.TabExtraParams, 'numeric');
            app.MikrokrokyEditFieldLabel = uilabel(app.TabExtraParams);
            app.MikrokrokyEditFieldLabel.Text = 'Mikrokroky';
            app.EditFieldMicrostepping = uieditfield(app.TabExtraParams, 'numeric');
            app.EditFieldMicrostepping.Value = 16;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end